classdef DELLO_loc_Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes_1               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        Slider_1               matlab.ui.control.Slider
        Slider_2               matlab.ui.control.Slider
        Slider_3               matlab.ui.control.Slider
        Slider_4               matlab.ui.control.Slider
        TargetButton           matlab.ui.control.Button
        EntryButton            matlab.ui.control.Button
        EditField              matlab.ui.control.EditField
        EditField_2Label       matlab.ui.control.Label
        EditField_2            matlab.ui.control.EditField
        DepthElectrodesCoordinatesLabel  matlab.ui.control.Label
        EditField_3            matlab.ui.control.EditField
        ElectrodesNameLabel    matlab.ui.control.Label
        NumberofChannelsLabel  matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        NextButton             matlab.ui.control.Button
        PrevButton             matlab.ui.control.Button
        DoneButton             matlab.ui.control.Button
        ManualLocateButton     matlab.ui.control.Button
        AutoLocateButton       matlab.ui.control.Button
    end


    properties (Access = public)
        ProcessedObj % Preprocessed object
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PrepObj)
            app.ProcessedObj = PrepObj;
            app.Slider_1.Limits = [1 size(app.ProcessedObj.AnatMat,1)];
            app.Slider_2.Limits = [1 size(app.ProcessedObj.AnatMat,2)];
            app.Slider_3.Limits = [1 size(app.ProcessedObj.AnatMat,3)];
            app.Slider_4.Limits = [1 size(app.ProcessedObj.Corr,1)];
            camroll(app.UIAxes_1,90)
            camroll(app.UIAxes_2,90)
            camroll(app.UIAxes_3,90)
        end

        % Callback function
        function Slider_1ValueChanging(app, event)

        end

        % Value changed function: Slider_1
        function Slider_1ValueChanged(app, event)
            value = app.Slider_1.Value;
            Xcorr = round(value);
           
            Atemp = squeeze(app.ProcessedObj.AnatMat(Xcorr,:,:));
            Btemp = squeeze(app.ProcessedObj.CTmat(Xcorr,:,:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_1,Ctemp);
            colormap(app.UIAxes_1,'gray')
            axis(app.UIAxes_1,'tight')
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            Xcorr = round(value);
            Atemp = squeeze(app.ProcessedObj.AnatMat(:,Xcorr,:));
            Btemp = squeeze(app.ProcessedObj.CTmat(:,Xcorr,:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_2,Ctemp);
            colormap(app.UIAxes_2,'gray')
            axis(app.UIAxes_2,'tight')
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            Xcorr = round(value);           
            Atemp = squeeze(app.ProcessedObj.AnatMat(:,:,Xcorr));
            Btemp = squeeze(app.ProcessedObj.CTmat(:,:,Xcorr));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_3,Ctemp);
            colormap(app.UIAxes_3,'gray')
            axis(app.UIAxes_3,'tight')
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            CorrTemp = app.ProcessedObj.Corr(round(value),:);
            
            % Axis 1
            Atemp = squeeze(app.ProcessedObj.AnatMat(round(CorrTemp(1)),:,:));
            Btemp = squeeze(app.ProcessedObj.CTmat(round(CorrTemp(1)),:,:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_1,Ctemp);
            hold(app.UIAxes_1,'on')
            plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
            colormap(app.UIAxes_1,'gray')
            axis(app.UIAxes_1,'tight')
                        
            % Axis 2
            Atemp = squeeze(app.ProcessedObj.AnatMat(:,round(CorrTemp(2)),:));
            Btemp = squeeze(app.ProcessedObj.CTmat(:,round(CorrTemp(2)),:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_2,Ctemp);
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
            colormap(app.UIAxes_2,'gray')
            axis(app.UIAxes_2,'tight')
            
            % Axis 3
            Atemp = squeeze(app.ProcessedObj.AnatMat(:,:,round(CorrTemp(3))));
            Btemp = squeeze(app.ProcessedObj.CTmat(:,:,round(CorrTemp(3))));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_3,Ctemp);
            hold(app.UIAxes_3,'on')
            plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
            colormap(app.UIAxes_3,'gray')
            axis(app.UIAxes_3,'tight')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Slice1')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Slice2')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Slice3')
            app.Slider_1 = uislider(app.UIFigure);
            app.Slider_1.MajorTicks = [];
            app.Slider_1.MajorTickLabels = {};
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_1.MinorTicks = [];
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MajorTickLabels = {};
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MajorTickLabels = {};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.MinorTicks = [];
            app.TargetButton = uibutton(app.UIFigure, 'push');
            app.TargetButton.Text = 'Target';
            app.EntryButton = uibutton(app.UIFigure, 'push');
            app.EntryButton.Text = 'Entry';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.DepthElectrodesCoordinatesLabel = uilabel(app.UIFigure);
            app.DepthElectrodesCoordinatesLabel.Text = 'Depth Electrodes Coordinates';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.ElectrodesNameLabel = uilabel(app.UIFigure);
            app.ElectrodesNameLabel.Text = 'Electrodes Name';
            app.NumberofChannelsLabel = uilabel(app.UIFigure);
            app.NumberofChannelsLabel.Text = 'Number of Channels';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'8', '10', '12', '14', '16'};
            app.DropDown.Value = '8';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.Text = 'Prev.';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.Text = 'Done';
            app.ManualLocateButton = uibutton(app.UIFigure, 'push');
            app.ManualLocateButton.Text = 'Manual Locate';
            app.AutoLocateButton = uibutton(app.UIFigure, 'push');
            app.AutoLocateButton.Text = 'Auto Locate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DELLO_loc_Panel(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end