classdef DELLO_Review_Pannel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DELLO_ReviewUIFigure       matlab.ui.Figure
        YEO7drop                   matlab.ui.control.DropDown
        AALDrop                    matlab.ui.control.DropDown
        GreyWhiteDrop              matlab.ui.control.DropDown
        MannualcorrectionLabel     matlab.ui.control.Label
        Yeo7AtlasLabel_2           matlab.ui.control.Label
        AAL3AtlasLabel_2           matlab.ui.control.Label
        GreyWhiteMatterLabel_2     matlab.ui.control.Label
        Yeo7AtlasLabel             matlab.ui.control.Label
        AAL3AtlasLabel             matlab.ui.control.Label
        GreyWhiteMatterLabel       matlab.ui.control.Label
        GWEditField                matlab.ui.control.EditField
        YEO7EditField              matlab.ui.control.EditField
        AAL3EditField              matlab.ui.control.EditField
        UITableElelctrode          matlab.ui.control.Table
        ExportElectrodeInfoButton  matlab.ui.control.Button
        ExportSlicesButton         matlab.ui.control.Button
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_1                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ElectrodeNames
        ElectrodePos
        ElectrodeMNIPos
        
        IndividualMRI
        
        AAL3Matrix
        AAL3Labels
        
        Yeo7Matrix
        Yeo7Labels
        
        GreyMatrix
        AnatMat
        CTmat
        
        DepthElectrodeObj
        
        CorrTempRaw

        crtIndx
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DepthObj)
            app.DepthElectrodeObj = DepthObj;
            % app.AnatMat = niftiread(app.DepthElectrodeObj.AnatFile);
            
            % Anat
            infoMRI   = niftiinfo(app.DepthElectrodeObj.AnatFile);
            app.AnatMat = niftiread(infoMRI);
            % CT with brain mask
            if exist('postCT.nii')
                infoCT   = niftiinfo('postCT.nii');
            else
                infoCT   = infoMRI;
            end
            
            app.CTmat = niftiread(infoCT);
            % Brain mask threshold
            BrainMaskF  = niftiinfo('BrainMask.nii');
            BrainMask   = niftiread(BrainMaskF);
            app.CTmat(~BrainMask) = 0;
            
            % Electrode Names
            app.ElectrodeNames = app.DepthElectrodeObj.ElectrodeName;
            app.UITableElelctrode.Data = app.ElectrodeNames;
            
            % Initialize the axis
            camroll(app.UIAxes_1,90)
            camroll(app.UIAxes_2,90)
            camroll(app.UIAxes_3,90)
            
            app.UIAxes_1.XTickLabel = [];
            app.UIAxes_2.XTickLabel = [];
            app.UIAxes_3.XTickLabel = [];
            
            app.UIAxes_1.YTickLabel = [];
            app.UIAxes_2.YTickLabel = [];
            app.UIAxes_3.YTickLabel = [];
            
            app.UIAxes_1.XColor = [1 1 1];
            app.UIAxes_2.XColor = [1 1 1];
            app.UIAxes_3.XColor = [1 1 1];
            
            app.UIAxes_1.YColor = [1 1 1];
            app.UIAxes_2.YColor = [1 1 1];
            app.UIAxes_3.YColor = [1 1 1];
        end

        % Callback function
        function ImportNameFileButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            ElectrodeNameFile = [path,file];
            app.ElectrodeNames = importdata(ElectrodeNameFile);
            app.UITableElelctrode.Data = app.ElectrodeNames;

        end

        % Cell selection callback: UITableElelctrode
        function UITableElelctrodeCellSelection(app, event)
            % reset the drop down list to default
            app.GreyWhiteDrop.Value = 'Default';
            app.AALDrop.Value       = 'Default';
            app.YEO7drop.Value      = 'Default';

            value = event.Indices(1);
            app.crtIndx = value;
            IndividualCorr = app.DepthElectrodeObj.ElectrodePos(value,:);
            % Convert to slice ijk
            % In MNI space
            infoFST1 = niftiinfo(app.DepthElectrodeObj.AnatFile);
            M_FS = infoFST1.Transform.T';
            
            % Read contacts raw coordinates
            ContactsPos = IndividualCorr;
            ContactsPos(:,4) = 1;
            
            
            ContactsPostionsNewijk = zeros(size(ContactsPos,1),4);
            
            for i = 1:size(ContactsPos,1)
                ContactsPostionsNewijk(i,:) = inv(M_FS)*ContactsPos(i,:)';
            end
            
            ContactsPostionsNewijk = ContactsPostionsNewijk(:,1:3);
            ContactsPostionsNewijk = round(ContactsPostionsNewijk);
            
            app.CorrTempRaw = ContactsPostionsNewijk;
            CorrTemp  = [app.CorrTempRaw(:,1),app.CorrTempRaw(:,2),app.CorrTempRaw(:,3)];
            
            % Axis 1
            Atemp = squeeze(app.AnatMat(round(CorrTemp(1)),:,:));
            Btemp = squeeze(app.CTmat(round(CorrTemp(1)),:,:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_1,Ctemp);
            hold(app.UIAxes_1,'on')
            plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
            colormap(app.UIAxes_1,'gray')
            axis(app.UIAxes_1,'tight')
            
            % Axis 2
            Atemp = squeeze(app.AnatMat(:,round(CorrTemp(2)),:));
            Btemp = squeeze(app.CTmat(:,round(CorrTemp(2)),:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_2,Ctemp);
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
            colormap(app.UIAxes_2,'gray')
            axis(app.UIAxes_2,'tight')
            
            % Axis 3
            Atemp = squeeze(app.AnatMat(:,:,round(CorrTemp(3))));
            Btemp = squeeze(app.CTmat(:,:,round(CorrTemp(3))));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_3,Ctemp);
            hold(app.UIAxes_3,'on')
            plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
            colormap(app.UIAxes_3,'gray')
            axis(app.UIAxes_3,'tight')

            if app.DepthElectrodeObj.OutBrain(value) == 1
                app.GWEditField.Value = 'Out Brain';
            elseif app.DepthElectrodeObj.OutBrain(value) == 0
                if app.DepthElectrodeObj.WhiteMatterLoc(value) == 0
                    app.GWEditField.Value = 'Grey matter';
                elseif app.DepthElectrodeObj.WhiteMatterLoc(value) == 1
                    app.GWEditField.Value = 'White matter';
                end
            end
            
            if app.DepthElectrodeObj.AAL3Index(value) == 0
                app.AAL3EditField.Value = 'Not Available';
            else
                app.AAL3EditField.Value = app.DepthElectrodeObj.AAL3Labels.textdata{app.DepthElectrodeObj.AAL3Index(value),2};
            end
            
            
            if app.DepthElectrodeObj.Yeo7Index(value) == 0
                app.YEO7EditField.Value = 'Not available';
            else
                app.YEO7EditField.Value = app.DepthElectrodeObj.Yeo7Labels{app.DepthElectrodeObj.Yeo7Index(value)};
                
            end
        end

        % Button pushed function: ExportSlicesButton
        function ExportSlicesButtonPushed(app, event)
            app.DepthElectrodeObj.ExportSlices
        end

        % Button pushed function: ExportElectrodeInfoButton
        function ExportElectrodeInfoButtonPushed(app, event)
            app.DepthElectrodeObj.ExportResultTable            
        end

        % Value changed function: GreyWhiteDrop
        function GreyWhiteDropValueChanged(app, event)
            mLabel = app.GreyWhiteDrop.Value;
            value = app.crtIndx;
            if strcmp(mLabel,'Out Brain')
                app.DepthElectrodeObj.OutBrain(value) = 1;
            elseif strcmp(mLabel,'Grey matter')
                app.DepthElectrodeObj.WhiteMatterLoc(value) = 0;
                app.DepthElectrodeObj.OutBrain(value) = 0;
            elseif strcmp(mLabel,'White matter')
                app.DepthElectrodeObj.WhiteMatterLoc(value) = 1;
                app.DepthElectrodeObj.OutBrain(value) = 0;
            end

            % Update the edit field
            if app.DepthElectrodeObj.OutBrain(value) == 1
                app.GWEditField.Value = 'Out Brain';
            elseif app.DepthElectrodeObj.OutBrain(value) == 0
                if app.DepthElectrodeObj.WhiteMatterLoc(value) == 0
                    app.GWEditField.Value = 'Grey matter';
                elseif app.DepthElectrodeObj.WhiteMatterLoc(value) == 1
                    app.GWEditField.Value = 'White matter';
                end
            end

        end

        % Value changed function: AALDrop
        function AALDropValueChanged(app, event)
            mLabel = app.AALDrop.Value;
            value = app.crtIndx;
            if strcmp(mLabel,'NoUse')
                app.DepthElectrodeObj.AAL3Index(value) = 0;
            else
                tempIndx = 0;
                tempIndx = find(strcmp(mLabel,app.DepthElectrodeObj.AAL3Labels.textdata(:,2)));
                app.DepthElectrodeObj.AAL3Index(value) = tempIndx;
            end

            % Update the AAL editfield
            if app.DepthElectrodeObj.AAL3Index(value) == 0
                app.AAL3EditField.Value = 'Not Available';
            else
                app.AAL3EditField.Value = app.DepthElectrodeObj.AAL3Labels.textdata{app.DepthElectrodeObj.AAL3Index(value),2};
            end

        end

        % Value changed function: YEO7drop
        function YEO7dropValueChanged(app, event)
            mLabel = app.YEO7drop.Value;
            value = app.crtIndx;
            if strcmp(mLabel,'NoUse')
                app.DepthElectrodeObj.Yeo7Index(value) = 0;
            else
                tempIndx = 0;
                tempIndx = find(strcmp(mLabel,app.DepthElectrodeObj.Yeo7Labels(:)));
                app.DepthElectrodeObj.Yeo7Index(value) = tempIndx;
            end

            % Update the AAL editfield
            if app.DepthElectrodeObj.Yeo7Index(value) == 0
                app.YEO7EditField.Value = 'Not available';
            else
                app.YEO7EditField.Value = app.DepthElectrodeObj.Yeo7Labels{app.DepthElectrodeObj.Yeo7Index(value)};
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DELLO_ReviewUIFigure = uifigure('Visible', 'off');
            app.DELLO_ReviewUIFigure.Name = 'DELLO_Review';
            app.UIAxes_1 = uiaxes(app.DELLO_ReviewUIFigure);
            title(app.UIAxes_1, 'SliceView1')
            app.UIAxes_2 = uiaxes(app.DELLO_ReviewUIFigure);
            title(app.UIAxes_2, 'SliceView2')
            app.UIAxes_3 = uiaxes(app.DELLO_ReviewUIFigure);
            title(app.UIAxes_3, 'SliceView3')
            app.ExportSlicesButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ExportSlicesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSlicesButtonPushed, true);
            app.ExportSlicesButton.Text = 'Export Slices';
            app.ExportElectrodeInfoButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ExportElectrodeInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ExportElectrodeInfoButtonPushed, true);
            app.ExportElectrodeInfoButton.Text = 'Export Electrode Info';
            app.UITableElelctrode = uitable(app.DELLO_ReviewUIFigure);
            app.UITableElelctrode.ColumnName = {'Electrode'};
            app.UITableElelctrode.RowName = {};
            app.UITableElelctrode.CellSelectionCallback = createCallbackFcn(app, @UITableElelctrodeCellSelection, true);
            app.AAL3EditField = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.YEO7EditField = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.GWEditField = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.GreyWhiteMatterLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.GreyWhiteMatterLabel.Text = 'Grey & White Matter';
            app.AAL3AtlasLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.AAL3AtlasLabel.Text = 'AAL3 Atlas';
            app.Yeo7AtlasLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.Yeo7AtlasLabel.Text = 'Yeo 7 Atlas';
            app.GreyWhiteMatterLabel_2 = uilabel(app.DELLO_ReviewUIFigure);
            app.GreyWhiteMatterLabel_2.Text = 'Grey & White Matter';
            app.AAL3AtlasLabel_2 = uilabel(app.DELLO_ReviewUIFigure);
            app.AAL3AtlasLabel_2.Text = 'AAL3 Atlas';
            app.Yeo7AtlasLabel_2 = uilabel(app.DELLO_ReviewUIFigure);
            app.Yeo7AtlasLabel_2.Text = 'Yeo 7 Atlas';
            app.MannualcorrectionLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.MannualcorrectionLabel.Text = 'Mannual correction';
            app.GreyWhiteDrop = uidropdown(app.DELLO_ReviewUIFigure);
            app.GreyWhiteDrop.Items = {'Default', 'Grey matter', 'White matter', 'Out Brain'};
            app.GreyWhiteDrop.ValueChangedFcn = createCallbackFcn(app, @GreyWhiteDropValueChanged, true);
            app.GreyWhiteDrop.Value = 'Default';
            app.AALDrop = uidropdown(app.DELLO_ReviewUIFigure);
            app.AALDrop.Items = {'Default', 'NoUse', 'Precentral_L', 'Precentral_R', 'Frontal_Sup_2_L', 'Frontal_Sup_2_R', 'Frontal_Mid_2_L', 'Frontal_Mid_2_R', 'Frontal_Inf_Oper_L', 'Frontal_Inf_Oper_R', 'Frontal_Inf_Tri_L', 'Frontal_Inf_Tri_R', 'Frontal_Inf_Orb_2_L', 'Frontal_Inf_Orb_2_R', 'Rolandic_Oper_L', 'Rolandic_Oper_R', 'Supp_Motor_Area_L', 'Supp_Motor_Area_R', 'Olfactory_L', 'Olfactory_R', 'Frontal_Sup_Medial_L', 'Frontal_Sup_Medial_R', 'Frontal_Med_Orb_L', 'Frontal_Med_Orb_R', 'Rectus_L', 'Rectus_R', 'OFCmed_L', 'OFCmed_R', 'OFCant_L', 'OFCant_R', 'OFCpost_L', 'OFCpost_R', 'OFClat_L', 'OFClat_R', 'Insula_L', 'Insula_R', 'Cingulate_Ant_L ', 'Cingulate_Ant_R ', 'Cingulate_Mid_L', 'Cingulate_Mid_R', 'Cingulate_Post_L', 'Cingulate_Post_R', 'Hippocampus_L', 'Hippocampus_R', 'ParaHippocampal_L', 'ParaHippocampal_R', 'Amygdala_L', 'Amygdala_R', 'Calcarine_L', 'Calcarine_R', 'Cuneus_L', 'Cuneus_R', 'Lingual_L', 'Lingual_R', 'Occipital_Sup_L', 'Occipital_Sup_R', 'Occipital_Mid_L', 'Occipital_Mid_R', 'Occipital_Inf_L', 'Occipital_Inf_R', 'Fusiform_L', 'Fusiform_R', 'Postcentral_L', 'Postcentral_R', 'Parietal_Sup_L', 'Parietal_Sup_R', 'Parietal_Inf_L', 'Parietal_Inf_R', 'SupraMarginal_L', 'SupraMarginal_R', 'Angular_L', 'Angular_R', 'Precuneus_L', 'Precuneus_R', 'Paracentral_Lobule_L', 'Paracentral_Lobule_R', 'Caudate_L', 'Caudate_R', 'Putamen_L', 'Putamen_R', 'Pallidum_L', 'Pallidum_R', 'Thalamus_L ', 'Thalamus_R ', 'Heschl_L', 'Heschl_R', 'Temporal_Sup_L', 'Temporal_Sup_R', 'Temporal_Pole_Sup_L', 'Temporal_Pole_Sup_R', 'Temporal_Mid_L', 'Temporal_Mid_R', 'Temporal_Pole_Mid_L', 'Temporal_Pole_Mid_R', 'Temporal_Inf_L', 'Temporal_Inf_R', 'Cerebellum_Crus1_L', 'Cerebellum_Crus1_R', 'Cerebellum_Crus2_L', 'Cerebellum_Crus2_R', 'Cerebellum_3_L', 'Cerebellum_3_R', 'Cerebellum_4_5_L', 'Cerebellum_4_5_R', 'Cerebellum_6_L', 'Cerebellum_6_R', 'Cerebellum_7b_L', 'Cerebellum_7b_R', 'Cerebellum_8_L', 'Cerebellum_8_R', 'Cerebellum_9_L', 'Cerebellum_9_R', 'Cerebellum_10_L', 'Cerebellum_10_R', 'Vermis_1_2', 'Vermis_3', 'Vermis_4_5', 'Vermis_6', 'Vermis_7', 'Vermis_8', 'Vermis_9', 'Vermis_10', 'Thal_AV_L', 'Thal_AV_R', 'Thal_LP_L', 'Thal_LP_R', 'Thal_VA_L', 'Thal_VA_R', 'Thal_VL_L', 'Thal_VL_R', 'Thal_VPL_L', 'Thal_VPL_R', 'Thal_IL_L', 'Thal_IL_R', 'Thal_Re_L', 'Thal_Re_R', 'Thal_MDm_L', 'Thal_MDm_R', 'Thal_MDl_L', 'Thal_MDl_R', 'Thal_LGN_L', 'Thal_LGN_R', 'Thal_MGN_L', 'Thal_MGN_R', 'Thal_PuI_L', 'Thal_PuI_R', 'Thal_PuM_L', 'Thal_PuM_R', 'Thal_PuA_L', 'Thal_PuA_R', 'Thal_PuL_L', 'Thal_PuL_R', 'ACC_sub_L', 'ACC_sub_R', 'ACC_pre_L', 'ACC_pre_R', 'ACC_sup_L', 'ACC_sup_R', 'N_Acc_L', 'N_Acc_R', 'VTA_L', 'VTA_R', 'SN_pc_L', 'SN_pc_R', 'SN_pr_L', 'SN_pr_R', 'Red_N_L', 'Red_N_R', 'LC_L', 'LC_R', 'Raphe_D', 'Raphe_M'};
            app.AALDrop.ValueChangedFcn = createCallbackFcn(app, @AALDropValueChanged, true);
            app.AALDrop.Value = 'Default';
            app.YEO7drop = uidropdown(app.DELLO_ReviewUIFigure);
            app.YEO7drop.Items = {'Default', 'NoUse', '  1     Visual', '  2     Somatomotor', '  3     Dorsal_Attention', '  4     Ventral_Attention', '  5     Limbic', '  6     Frontoparietal', '  7     Default'};
            app.YEO7drop.ValueChangedFcn = createCallbackFcn(app, @YEO7dropValueChanged, true);
            app.YEO7drop.Value = 'Default';
            % Show the figure after all components are created
            app.DELLO_ReviewUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DELLO_Review_Pannel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DELLO_ReviewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DELLO_ReviewUIFigure)
        end
    end
end