classdef DELLO_loc_Panel_auto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DELLOLocAutoUIFigure           matlab.ui.Figure
        ElectrodeInformationSelectionLabel  matlab.ui.control.Label
        UnlabeledElectrodeGroupsLabel  matlab.ui.control.Label
        ElectrodeIndexTable            matlab.ui.control.Table
        Slice3LeftArrow                matlab.ui.control.Button
        Slice2LeftArrow                matlab.ui.control.Button
        Slice1LeftArrow                matlab.ui.control.Button
        Slice3RightArrow               matlab.ui.control.Button
        Slice2RightArrow               matlab.ui.control.Button
        Slice1RightArrow               matlab.ui.control.Button
        DepthLeftArrow                 matlab.ui.control.Button
        DepthRightArrow                matlab.ui.control.Button
        ElectrodeButton                matlab.ui.control.Button
        EditFieldElectrode             matlab.ui.control.EditField
        GenerateButton                 matlab.ui.control.Button
        UITableElecInfo                matlab.ui.control.Table
        FinishButton                   matlab.ui.control.Button
        DepthElectrodesCoordinatesLabel  matlab.ui.control.Label
        EditFieldEntryCorr             matlab.ui.control.EditField
        EditField_2Label               matlab.ui.control.Label
        EditFieldTargetCorr            matlab.ui.control.EditField
        EntryButton                    matlab.ui.control.Button
        TargetButton                   matlab.ui.control.Button
        Slider_4                       matlab.ui.control.Slider
        Slider_3                       matlab.ui.control.Slider
        Slider_2                       matlab.ui.control.Slider
        Slider_1                       matlab.ui.control.Slider
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_1                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ProcessedObj % Preprocessed object
        AnatMat
        CTmat
        MRICTfusedMat
        
        ElecNames
        TargetCorr
        EntryCorr
        dim_limit = 240


    end
    
    properties (Access = private)
        CurrentGrpInd % Description
        CurrentSelectedElec
        CurrentSelectedElecIndex
        
        CorrTempRawIJK
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PrepObj)
            app.ProcessedObj = PrepObj;
            % Load the image matrix
            % Anat
            infoMRI   = niftiinfo('r3DT1.nii');
            app.AnatMat = int16(niftiread(infoMRI));
            % CT with brain mask
            infoCT   = niftiinfo(PrepObj.PostCT);
            app.CTmat = int16(niftiread(infoCT));
            % Brain mask threshold
            BrainMaskF  = niftiinfo('BrainMask.nii');
            BrainMask   = int16(niftiread(BrainMaskF));
            app.CTmat(~BrainMask) = 0;
            
            % Init
            app.CurrentGrpInd = 1;
            IndividualCorr = app.ProcessedObj.RawElecPos(app.ProcessedObj.RawElecGrpInd == app.CurrentGrpInd,:);
            app.Slider_4.Limits = [1 size(IndividualCorr,1)];
            % Disp
            app.Slider_1.Limits = [1 size(app.AnatMat,1)];
            app.Slider_2.Limits = [1 size(app.AnatMat,2)];
            app.Slider_3.Limits = [1 size(app.AnatMat,3)];
            
            % Initialize the axis
            camroll(app.UIAxes_1,90)
            camroll(app.UIAxes_2,90)
            camroll(app.UIAxes_3,90)

            app.UIAxes_1.XTickLabel = [];
            app.UIAxes_2.XTickLabel = [];
            app.UIAxes_3.XTickLabel = [];
            
            app.UIAxes_1.YTickLabel = [];
            app.UIAxes_2.YTickLabel = [];
            app.UIAxes_3.YTickLabel = [];
            
            app.UIAxes_1.XColor = [1 1 1];
            app.UIAxes_2.XColor = [1 1 1];
            app.UIAxes_3.XColor = [1 1 1];
            
            app.UIAxes_1.YColor = [1 1 1];
            app.UIAxes_2.YColor = [1 1 1];
            app.UIAxes_3.YColor = [1 1 1];
            
            % Fill the electrode info table
            app.UITableElecInfo.Data = app.ProcessedObj.ElectrodeInfo;
            app.UITableElecInfo.Data(:,3) = cellstr(repmat('pending',max(app.ProcessedObj.RawElecGrpInd),1));
            
            % Fill the ElectrodeIndex table
            app.ElectrodeIndexTable.Data = num2cell(1:max(app.ProcessedObj.RawElecGrpInd))';
            app.ElectrodeIndexTable.Data(:,2) = cellstr(repmat('pending',max(app.ProcessedObj.RawElecGrpInd),1));
            
            
            % Update the fused images when starting
            sizeCT = size(app.CTmat);
            sizeMRI = size(app.AnatMat);
            assert(all(sizeCT == sizeMRI));
            
            % Scale the image first
            
%             app.CTmat = (app.CTmat - min(app.CTmat,[],'all'))./ max(app.CTmat,[],'all');
%             app.AnatMat = (app.AnatMat - min(app.AnatMat,[],'all'))./ max(app.AnatMat,[],'all');
            
            app.MRICTfusedMat = app.AnatMat .* 0.8 + app.CTmat .* 0.2;
            
            % Wait until all electrodes labeled
            uiwait(app.DELLOLocAutoUIFigure)

            
        end

        % Callback function
        function Slider_1ValueChanging(app, event)
            
        end

        % Value changed function: Slider_1
        function Slider_1ValueChanged(app, event)
            
            if ~isempty(app.CorrTempRawIJK)
                value = app.Slider_1.Value;
                Xcorr = round(value);
                app.CorrTempRawIJK(3) = Xcorr;
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
%                 Atemp = squeeze(app.AnatMat(round(CorrTemp(1)),:,:));
%                 Btemp = squeeze(app.CTmat(round(CorrTemp(1)),:,:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
                
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2
%                 Atemp = squeeze(app.AnatMat(:,round(CorrTemp(2)),:));
%                 Btemp = squeeze(app.CTmat(:,round(CorrTemp(2)),:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
%                 
                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
%                 Atemp = squeeze(app.AnatMat(:,:,round(CorrTemp(3))));
%                 Btemp = squeeze(app.CTmat(:,:,round(CorrTemp(3))));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')

%                 disp(CorrTemp)
          
            end
            
%             value = app.Slider_1.Value;
%             Xcorr = round(value);
%             
%             Atemp = squeeze(app.AnatMat(Xcorr,:,:));
%             Btemp = squeeze(app.CTmat(Xcorr,:,:));
%             Ctemp = imfuse(Atemp,Btemp,'blend');
%             im = imagesc(app.UIAxes_1,Ctemp);
%             colormap(app.UIAxes_1,'gray')
%             axis(app.UIAxes_1,'tight')
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            
            if ~isempty(app.CorrTempRawIJK)
                value = app.Slider_2.Value;
                Xcorr = round(value);
                app.CorrTempRawIJK(2) = Xcorr;
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
%                 Atemp = squeeze(app.AnatMat(round(CorrTemp(1)),:,:));
%                 Btemp = squeeze(app.CTmat(round(CorrTemp(1)),:,:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');

                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2
%                 Atemp = squeeze(app.AnatMat(:,round(CorrTemp(2)),:));
%                 Btemp = squeeze(app.CTmat(:,round(CorrTemp(2)),:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
 
                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
%                 Atemp = squeeze(app.AnatMat(:,:,round(CorrTemp(3))));
%                 Btemp = squeeze(app.CTmat(:,:,round(CorrTemp(3))));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');

                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end


%             value = app.Slider_2.Value;
%             Xcorr = round(value);
%             Atemp = squeeze(app.AnatMat(:,Xcorr,:));
%             Btemp = squeeze(app.CTmat(:,Xcorr,:));
%             Ctemp = imfuse(Atemp,Btemp,'blend');
%             im = imagesc(app.UIAxes_2,Ctemp);
%             colormap(app.UIAxes_2,'gray')
%             axis(app.UIAxes_2,'tight')
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            
            if ~isempty(app.CorrTempRawIJK)
                value = app.Slider_3.Value;
                Xcorr = round(value);
                app.CorrTempRawIJK(1) = Xcorr;
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
%                 Atemp = squeeze(app.AnatMat(round(CorrTemp(1)),:,:));
%                 Btemp = squeeze(app.CTmat(round(CorrTemp(1)),:,:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');

                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2
%                 Atemp = squeeze(app.AnatMat(:,round(CorrTemp(2)),:));
%                 Btemp = squeeze(app.CTmat(:,round(CorrTemp(2)),:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');

                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
%                 Atemp = squeeze(app.AnatMat(:,:,round(CorrTemp(3))));
%                 Btemp = squeeze(app.CTmat(:,:,round(CorrTemp(3))));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');

                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end
%             
%             value = app.Slider_3.Value;
%             Xcorr = round(value);
%             Atemp = squeeze(app.AnatMat(:,:,Xcorr));
%             Btemp = squeeze(app.CTmat(:,:,Xcorr));
%             Ctemp = imfuse(Atemp,Btemp,'blend');
%             im = imagesc(app.UIAxes_3,Ctemp);
%             colormap(app.UIAxes_3,'gray')
%             axis(app.UIAxes_3,'tight')
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            
            value = app.Slider_4.Value;
            IndividualCorr = app.ProcessedObj.RawElecPos(app.ProcessedObj.RawElecGrpInd == app.CurrentGrpInd,:);
            app.Slider_4.Limits = [1 size(IndividualCorr,1)];
            app.CorrTempRawIJK = IndividualCorr(round(value),:);
            CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
            
            % Axis 1
%             Atemp = squeeze(app.AnatMat(round(CorrTemp(1)),:,:));
%             Btemp = squeeze(app.CTmat(round(CorrTemp(1)),:,:));
%             Ctemp = imfuse(Atemp,Btemp,'blend');
            
            
            Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
            im = imagesc(app.UIAxes_1,Ctemp);
            im.HitTest = "off";
            hold(app.UIAxes_1,'on')
            plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
            colormap(app.UIAxes_1,'gray')
            axis(app.UIAxes_1,'tight')
            
            % Axis 2
%             Atemp = squeeze(app.AnatMat(:,round(CorrTemp(2)),:));
%             Btemp = squeeze(app.CTmat(:,round(CorrTemp(2)),:));
%             Ctemp = imfuse(Atemp,Btemp,'blend');

            Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
            im = imagesc(app.UIAxes_2,Ctemp);
            im.HitTest = "off";
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
            colormap(app.UIAxes_2,'gray')
            axis(app.UIAxes_2,'tight')
            
            % Axis 3
%             Atemp = squeeze(app.AnatMat(:,:,round(CorrTemp(3))));
%             Btemp = squeeze(app.CTmat(:,:,round(CorrTemp(3))));
%             Ctemp = imfuse(Atemp,Btemp,'blend');

            Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
            im = imagesc(app.UIAxes_3,Ctemp);
            im.HitTest = "off";
            hold(app.UIAxes_3,'on')
            plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
            colormap(app.UIAxes_3,'gray')
            axis(app.UIAxes_3,'tight')
            
            % Control the other three axis
            CorrTempInd = round(CorrTemp);
            app.Slider_1.Value = CorrTempInd(1);
            
            CorrTempInd = round(CorrTempInd);
            app.Slider_2.Value = CorrTempInd(2);
            
            CorrTempInd = round(CorrTempInd);
            app.Slider_3.Value = CorrTempInd(3);
            
        end

        % Button pushed function: TargetButton
        function TargetButtonPushed(app, event)
            if isempty(app.CurrentSelectedElec)
                app.EditFieldTargetCorr.Value = 'Select name first';
            else
                app.EditFieldTargetCorr.Value = num2str(app.CorrTempRawIJK,4);
                app.TargetCorr(app.CurrentSelectedElec(1,1),:) = app.CorrTempRawIJK;
            end
        end

        % Callback function: EditFieldEntryCorr, FinishButton
        function EditFieldEntryCorrValueChanged(app, event)
            
            uiresume(app.DELLOLocAutoUIFigure)
        end

        % Button pushed function: EntryButton
        function EntryButtonPushed(app, event)
            if isempty(app.CurrentSelectedElec)
                app.EditFieldEntryCorr.Value = 'Select name first';
            else
                app.EditFieldEntryCorr.Value = num2str(app.CorrTempRawIJK,4);
                app.EntryCorr(app.CurrentSelectedElec(1,1),:) = app.CorrTempRawIJK;
            end
        end

        % Callback function
        function UIselectButtonPushed(app, event)
            
        end

        % Callback function
        function PreviousButtonPushed(app, event)
            if app.CurrentGrpInd == 1
                app.CurrentGrpInd = app.CurrentGrpInd;
            else
                app.CurrentGrpInd = app.CurrentGrpInd - 1;
            end
            
        end

        % Callback function
        function NextButtonPushed(app, event)
            if app.CurrentGrpInd == max(app.ProcessedObj.RawElecGrpInd)
                app.CurrentGrpInd = app.CurrentGrpInd;
            else
                app.CurrentGrpInd = app.CurrentGrpInd + 1;
            end
        end

        % Button pushed function: ElectrodeButton
        function ElectrodeButtonPushed(app, event)
            if isempty(app.CurrentSelectedElec)
                app.EditFieldElectrode.Value = 'Select name first';
            else
                app.EditFieldElectrode.Value = string(app.ProcessedObj.ElectrodeInfo.ElecName{app.CurrentSelectedElec(1,1)});
                
            end
            
            
        end

        % Cell selection callback: UITableElecInfo
        function UITableElecInfoCellSelection(app, event)
            app.CurrentSelectedElec = event.Indices;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.EditFieldElectrode.Value   = string([]);
            app.EditFieldEntryCorr.Value   = string([]);
            app.EditFieldTargetCorr.Value  = string([]);
            
            tempEleIdx  = app.ElectrodeIndexTable.Data(app.CurrentSelectedElecIndex,1);
            tempEleName = app.UITableElecInfo.Data(app.CurrentSelectedElec(1,1),1);
            
            app.UITableElecInfo.Data(app.CurrentSelectedElec(1,1),3)     = {tempEleIdx};
            app.ElectrodeIndexTable.Data(app.CurrentSelectedElecIndex,2) = tempEleName.ElecName;
            
        end

        % Button pushed function: DepthRightArrow
        function DepthRightArrowButtonPushed(app, event)
            
            value = app.Slider_4.Value;
            IndividualCorr = app.ProcessedObj.RawElecPos(app.ProcessedObj.RawElecGrpInd == app.CurrentGrpInd,:);
            app.Slider_4.Limits = [1 size(IndividualCorr,1)];
            if app.Slider_4.Value < max(app.Slider_4.Limits)
                app.Slider_4.Value = app.Slider_4.Value + 1;
                app.CorrTempRawIJK = IndividualCorr(round(value),:);
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                
                % Axis 1
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2

                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end
            
        end

        % Button pushed function: DepthLeftArrow
        function DepthLeftArrowButtonPushed(app, event)
            if app.Slider_4.Value > 1
                app.Slider_4.Value = app.Slider_4.Value - 1;
                value = app.Slider_4.Value;
                IndividualCorr = app.ProcessedObj.RawElecPos(app.ProcessedObj.RawElecGrpInd == app.CurrentGrpInd,:);
                app.Slider_4.Limits = [1 size(IndividualCorr,1)];
                app.CorrTempRawIJK = IndividualCorr(round(value),:);
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2

                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end
        end

        % Cell selection callback: ElectrodeIndexTable
        function ElectrodeIndexTableCellSelection(app, event)
            app.CurrentGrpInd = event.Indices(1);
            app.CurrentSelectedElecIndex = event.Indices(1);
        end

        % Cell edit callback: ElectrodeIndexTable
        function ElectrodeIndexTableCellEdit(app, event)
            
        end

        % Button pushed function: Slice1RightArrow
        function Slice1RightArrowButtonPushed(app, event)
            
            if ~isempty(app.CorrTempRawIJK)
                app.CorrTempRawIJK(3) = app.CorrTempRawIJK(3) + 1;
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2

                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end
        end

        % Button pushed function: Slice1LeftArrow
        function Slice1LeftArrowButtonPushed(app, event)
            if ~isempty(app.CorrTempRawIJK)
                app.CorrTempRawIJK(3) = app.CorrTempRawIJK(3) - 1;
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                                % Axis 1
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2

                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end
        end

        % Button pushed function: Slice2RightArrow
        function Slice2RightArrowButtonPushed(app, event)
            if ~isempty(app.CorrTempRawIJK)
                app.CorrTempRawIJK(2) = app.CorrTempRawIJK(2) + 1;
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2

                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end
        end

        % Button pushed function: Slice3RightArrow
        function Slice3RightArrowButtonPushed(app, event)
            if ~isempty(app.CorrTempRawIJK)
                app.CorrTempRawIJK(1) = app.CorrTempRawIJK(1) + 1;
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2

                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end
        end

        % Button pushed function: Slice2LeftArrow
        function Slice2LeftArrowButtonPushed(app, event)
            if ~isempty(app.CorrTempRawIJK)
                app.CorrTempRawIJK(2) = app.CorrTempRawIJK(2) - 1;
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2

                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end
        end

        % Button pushed function: Slice3LeftArrow
        function Slice3LeftArrowButtonPushed(app, event)
            if ~isempty(app.CorrTempRawIJK)
                app.CorrTempRawIJK(1) = app.CorrTempRawIJK(1) - 1;
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2

                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                
                % Axis 3
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
            end
        end

        % Button down function: UIAxes_1
        function UIAxes_1ButtonDown(app, event)
%             disp([app.UIAxes_1.CurrentPoint(1, :), app.UIAxes_1.XLim, app.UIAxes_1.YLim, app.dim_limit])
            corr2 = ceil(app.UIAxes_1.CurrentPoint(1, 2));
            corr1 = ceil(app.UIAxes_1.CurrentPoint(1, 1));
%             disp([corr1, corr2]);

            if ~isempty(app.CorrTempRawIJK)
                app.CorrTempRawIJK(1) = corr1;
                app.CorrTempRawIJK(2) = corr2;
%                 disp(app.CorrTempRawIJK)
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
%                 Atemp = squeeze(app.AnatMat(round(CorrTemp(1)),:,:));
%                 Btemp = squeeze(app.CTmat(round(CorrTemp(1)),:,:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
                
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
%                 disp([CorrTemp(3),CorrTemp(2)])
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')                
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2
%                 Atemp = squeeze(app.AnatMat(:,round(CorrTemp(2)),:));
%                 Btemp = squeeze(app.CTmat(:,round(CorrTemp(2)),:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
%                 
                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                app.Slider_2.Value = CorrTemp(2);
                
                % Axis 3
%                 Atemp = squeeze(app.AnatMat(:,:,round(CorrTemp(3))));
%                 Btemp = squeeze(app.CTmat(:,:,round(CorrTemp(3))));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
                app.Slider_3.Value = CorrTemp(3);

            end


        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
%             disp([app.UIAxes_2.CurrentPoint(1, :), app.UIAxes_1.XLim, app.UIAxes_1.YLim, app.dim_limit])
            corr2 = ceil(app.UIAxes_2.CurrentPoint(1, 2));
            corr1 = ceil(app.UIAxes_2.CurrentPoint(1, 1));
%             disp([corr1, corr2]);

            if ~isempty(app.CorrTempRawIJK)
                app.CorrTempRawIJK(1) = corr1;
                app.CorrTempRawIJK(3) = corr2;
%                 disp(app.CorrTempRawIJK)
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
%                 Atemp = squeeze(app.AnatMat(round(CorrTemp(1)),:,:));
%                 Btemp = squeeze(app.CTmat(round(CorrTemp(1)),:,:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
                
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
%                 disp([CorrTemp(3),CorrTemp(2)])
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')                
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2
%                 Atemp = squeeze(app.AnatMat(:,round(CorrTemp(2)),:));
%                 Btemp = squeeze(app.CTmat(:,round(CorrTemp(2)),:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
%                 
                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                app.Slider_2.Value = CorrTemp(2);
                
                % Axis 3
%                 Atemp = squeeze(app.AnatMat(:,:,round(CorrTemp(3))));
%                 Btemp = squeeze(app.CTmat(:,:,round(CorrTemp(3))));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
                app.Slider_3.Value = CorrTemp(3);

            end

        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
%             disp([app.UIAxes_3.CurrentPoint(1, :), app.UIAxes_1.XLim, app.UIAxes_1.YLim, app.dim_limit])
            corr2 = ceil(app.UIAxes_3.CurrentPoint(1, 2));
            corr1 = ceil(app.UIAxes_3.CurrentPoint(1, 1));
%             disp([corr1, corr2]);

            if ~isempty(app.CorrTempRawIJK)
                app.CorrTempRawIJK(2) = corr1;
                app.CorrTempRawIJK(3) = corr2;
%                 disp(app.CorrTempRawIJK)
                CorrTemp  = [app.CorrTempRawIJK(:,3),app.CorrTempRawIJK(:,2),app.CorrTempRawIJK(:,1)];
                
                % Axis 1
%                 Atemp = squeeze(app.AnatMat(round(CorrTemp(1)),:,:));
%                 Btemp = squeeze(app.CTmat(round(CorrTemp(1)),:,:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
                
                Ctemp = squeeze(app.MRICTfusedMat(round(CorrTemp(1)),:,:));
                
                im = imagesc(app.UIAxes_1,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_1,'on')
%                 disp([CorrTemp(3),CorrTemp(2)])
                plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')                
                colormap(app.UIAxes_1,'gray')
                axis(app.UIAxes_1,'tight')
                
                % Axis 2
%                 Atemp = squeeze(app.AnatMat(:,round(CorrTemp(2)),:));
%                 Btemp = squeeze(app.CTmat(:,round(CorrTemp(2)),:));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
%                 
                Ctemp = squeeze(app.MRICTfusedMat(:,round(CorrTemp(2)),:));
                
                im = imagesc(app.UIAxes_2,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_2,'on')
                plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
                colormap(app.UIAxes_2,'gray')
                axis(app.UIAxes_2,'tight')
                app.Slider_2.Value = CorrTemp(2);
                
                % Axis 3
%                 Atemp = squeeze(app.AnatMat(:,:,round(CorrTemp(3))));
%                 Btemp = squeeze(app.CTmat(:,:,round(CorrTemp(3))));
%                 Ctemp = imfuse(Atemp,Btemp,'blend');
                
                Ctemp = squeeze(app.MRICTfusedMat(:,:,round(CorrTemp(3))));
                
                im = imagesc(app.UIAxes_3,Ctemp);
                im.HitTest = "off";
                hold(app.UIAxes_3,'on')
                plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
                colormap(app.UIAxes_3,'gray')
                axis(app.UIAxes_3,'tight')
                app.Slider_3.Value = CorrTemp(3);

            end

        end

        % Window scroll wheel function: DELLOLocAutoUIFigure
        function DELLOLocAutoUIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            
            if is_in_axe(app.UIAxes_1.CurrentPoint, app.UIAxes_1.XLim, app.UIAxes_1.YLim)
                if verticalScrollCount > 0
                    app.Slice1RightArrowButtonPushed()
                else
                    app.Slice1LeftArrowButtonPushed()
                end
            end


            if is_in_axe(app.UIAxes_2.CurrentPoint, app.UIAxes_2.XLim, app.UIAxes_2.YLim)
                if verticalScrollCount > 0
                    app.Slice2RightArrowButtonPushed()
                else
                    app.Slice2LeftArrowButtonPushed()
                end
            end


            if is_in_axe(app.UIAxes_3.CurrentPoint, app.UIAxes_3.XLim, app.UIAxes_3.YLim)
                if verticalScrollCount > 0
                    app.Slice3RightArrowButtonPushed()
                else
                    app.Slice3LeftArrowButtonPushed()
                end
            end


            function rlt = is_in_axe(point, axe_XLim, axe_YLim)
                rlt = true;
                if any(point(1, [1, 2]) <= 0)
                    rlt = false;
                    return
                elseif point(1, 1) > axe_XLim(2)
                    rlt = false;
                    return
                elseif point(1, 2) > axe_YLim(2)
                    rlt = false;
                    return
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DELLOLocAutoUIFigure = uifigure('Visible', 'off');
            app.DELLOLocAutoUIFigure.Name = 'DELLO Loc Auto';
            app.DELLOLocAutoUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @DELLOLocAutoUIFigureWindowScrollWheel, true);
            app.UIAxes_1 = uiaxes(app.DELLOLocAutoUIFigure);
            title(app.UIAxes_1, 'SliceView1')
            app.UIAxes_1.ButtonDownFcn = createCallbackFcn(app, @UIAxes_1ButtonDown, true);
            app.UIAxes_2 = uiaxes(app.DELLOLocAutoUIFigure);
            title(app.UIAxes_2, 'SliceView2')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes_3 = uiaxes(app.DELLOLocAutoUIFigure);
            title(app.UIAxes_3, 'SliceView3')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.Slider_1 = uislider(app.DELLOLocAutoUIFigure);
            app.Slider_1.MajorTicks = [];
            app.Slider_1.MajorTickLabels = {};
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_1.MinorTicks = [];
            app.Slider_2 = uislider(app.DELLOLocAutoUIFigure);
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_3 = uislider(app.DELLOLocAutoUIFigure);
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MajorTickLabels = {};
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_4 = uislider(app.DELLOLocAutoUIFigure);
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MajorTickLabels = {};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.MinorTicks = [];
            app.TargetButton = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.TargetButton.ButtonPushedFcn = createCallbackFcn(app, @TargetButtonPushed, true);
            app.TargetButton.Text = 'Target';
            app.EntryButton = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.EntryButton.ButtonPushedFcn = createCallbackFcn(app, @EntryButtonPushed, true);
            app.EntryButton.Text = 'Entry';
            app.EditFieldTargetCorr = uieditfield(app.DELLOLocAutoUIFigure, 'text');
            app.EditField_2Label = uilabel(app.DELLOLocAutoUIFigure);
            app.EditField_2Label.Text = '';
            app.EditFieldEntryCorr = uieditfield(app.DELLOLocAutoUIFigure, 'text');
            app.EditFieldEntryCorr.ValueChangedFcn = createCallbackFcn(app, @EditFieldEntryCorrValueChanged, true);
            app.DepthElectrodesCoordinatesLabel = uilabel(app.DELLOLocAutoUIFigure);
            app.DepthElectrodesCoordinatesLabel.Text = 'Depth Electrodes Coordinates';
            app.FinishButton = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @EditFieldEntryCorrValueChanged, true);
            app.FinishButton.Text = 'Finish';
            app.UITableElecInfo = uitable(app.DELLOLocAutoUIFigure);
            app.UITableElecInfo.ColumnName = {'Names'; 'NChannel'; 'Check'};
            app.UITableElecInfo.RowName = {};
            app.UITableElecInfo.CellSelectionCallback = createCallbackFcn(app, @UITableElecInfoCellSelection, true);
            app.GenerateButton = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.EditFieldElectrode = uieditfield(app.DELLOLocAutoUIFigure, 'text');
            app.EditFieldElectrode.Editable = 'off';
            app.ElectrodeButton = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.ElectrodeButton.ButtonPushedFcn = createCallbackFcn(app, @ElectrodeButtonPushed, true);
            app.ElectrodeButton.Text = 'Electrode';
            app.DepthRightArrow = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.DepthRightArrow.ButtonPushedFcn = createCallbackFcn(app, @DepthRightArrowButtonPushed, true);
            app.DepthRightArrow.Text = '->';
            app.DepthLeftArrow = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.DepthLeftArrow.ButtonPushedFcn = createCallbackFcn(app, @DepthLeftArrowButtonPushed, true);
            app.DepthLeftArrow.Text = '<-';
            app.Slice1RightArrow = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.Slice1RightArrow.ButtonPushedFcn = createCallbackFcn(app, @Slice1RightArrowButtonPushed, true);
            app.Slice1RightArrow.Text = '->';
            app.Slice2RightArrow = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.Slice2RightArrow.ButtonPushedFcn = createCallbackFcn(app, @Slice2RightArrowButtonPushed, true);
            app.Slice2RightArrow.Text = '->';
            app.Slice3RightArrow = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.Slice3RightArrow.ButtonPushedFcn = createCallbackFcn(app, @Slice3RightArrowButtonPushed, true);
            app.Slice3RightArrow.Text = '->';
            app.Slice1LeftArrow = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.Slice1LeftArrow.ButtonPushedFcn = createCallbackFcn(app, @Slice1LeftArrowButtonPushed, true);
            app.Slice1LeftArrow.Text = '<-';
            app.Slice2LeftArrow = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.Slice2LeftArrow.ButtonPushedFcn = createCallbackFcn(app, @Slice2LeftArrowButtonPushed, true);
            app.Slice2LeftArrow.Text = '<-';
            app.Slice3LeftArrow = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.Slice3LeftArrow.ButtonPushedFcn = createCallbackFcn(app, @Slice3LeftArrowButtonPushed, true);
            app.Slice3LeftArrow.Text = '<-';
            app.ElectrodeIndexTable = uitable(app.DELLOLocAutoUIFigure);
            app.ElectrodeIndexTable.ColumnName = {'Electrodes'; 'Status'};
            app.ElectrodeIndexTable.RowName = {};
            app.ElectrodeIndexTable.CellEditCallback = createCallbackFcn(app, @ElectrodeIndexTableCellEdit, true);
            app.ElectrodeIndexTable.CellSelectionCallback = createCallbackFcn(app, @ElectrodeIndexTableCellSelection, true);
            app.UnlabeledElectrodeGroupsLabel = uilabel(app.DELLOLocAutoUIFigure);
            app.UnlabeledElectrodeGroupsLabel.Text = 'Unlabeled Electrode Groups';
            app.ElectrodeInformationSelectionLabel = uilabel(app.DELLOLocAutoUIFigure);
            app.ElectrodeInformationSelectionLabel.Text = 'Electrode Information Selection';
            % Show the figure after all components are created
            app.DELLOLocAutoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DELLO_loc_Panel_auto(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DELLOLocAutoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DELLOLocAutoUIFigure)
        end
    end
end